classdef SRecord_V3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BrowseAudioSavePathButton      matlab.ui.control.Button
        UserCaseLabel                  matlab.ui.control.Label
        BrowseUseCasetxtfileButton     matlab.ui.control.Button
        MustbeinthisformatS0001_U10_M100Label  matlab.ui.control.Label
        ListenToRecordingButton        matlab.ui.control.Button
        NextButton                     matlab.ui.control.Button
        PreviousButton_2               matlab.ui.control.Button
        AcceptRecordingButton          matlab.ui.control.Button
        StopRecordingButton            matlab.ui.control.Button
        StartRecordingButton           matlab.ui.control.Button
        EnterSessionID_UseCaseID_MaleIDFemaleIDEditField  matlab.ui.control.EditField
        EnterSessionID_UseCaseID_MaleIDFemaleIDEditFieldLabel  matlab.ui.control.Label
        SelectNoiseLevelDropDown       matlab.ui.control.DropDown
        SelectNoiseLevelDropDownLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        IDlength;
        dashCounter;
        dashArray;
        firstDash;
        secondDash;
        sessionID;
        userCaseID;
        Male_Female_ID;
        M_F_num_ID;
        M_F_letter;
        userLetter;
        Male_Female_ID_Length;
        sessionID_correct = 1;
        userCaseFile;
        totalTrials;
        n;
        textLines;
        folderName;
        audioRecorder;
        fs = 48000;
        nBits = 16;
        nChannels = 1;
        savepath;
        audio;
        maxRecordedPrompt = 1;
        hasGoneBack = false;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AcceptRecordingButton
        function AcceptRecordingButtonPushed(app, event)
            app.AcceptRecordingButton.BackgroundColor = [0.5 1 0.5];
            pause(0.2);
            app.AcceptRecordingButton.BackgroundColor = [0.96 0.96 0.96];
            fileName =  string(app.EnterSessionID_UseCaseID_MaleIDFemaleIDEditField.Value);
            hyphen = "_";
            fileNum = string(app.n);
            %f = fullfile(filepart1,...,filepartN)
            fileName = fileName + hyphen + fileNum + '.wav';
            fileName = char(fileName);
            fileName = fullfile(app.savepath, app.folderName, fileName);
            %disp(fileName);
            audiowrite(fileName, app.audio, app.fs);
            if app.n < app.totalTrials
                app.n = app.n + 1;
                app.UserCaseLabel.Text = app.textLines{app.n};
            else
                app.UserCaseLabel.Text = 'There are no more use cases, Thank you!';
            end
            if app.n > 1
                app.PreviousButton_2.Enable = 'on';
            else
                app.PreviousButton_2.Enable = 'off';
            end
            app.NextButton.Enable = 'off';
        end

        % Button pushed function: BrowseAudioSavePathButton
        function BrowseAudioSavePathButtonPushed(app, event)

            app.savepath = uigetdir;
            value = char(app.EnterSessionID_UseCaseID_MaleIDFemaleIDEditField.Value);
            if isempty(value)
                disp("You have not selected a folder yet");
            else
                app.folderName = value;
                mkdir(app.savepath, app.folderName);
            end
        end

        % Button pushed function: BrowseUseCasetxtfileButton
        function BrowseUseCasetxtfileButtonPushed(app, event)
           app.NextButton.Enable = 'off';
           [txtfile, txtpath] = uigetfile('*.txt');
           fid_lst = fopen(fullfile(txtpath, txtfile), 'rt');
           app.totalTrials = fscanf(fid_lst, '%d\n');
           app.textLines = textscan(fid_lst, '%s', 'Delimiter', '\n');
           app.textLines = app.textLines{1};
           fclose(fid_lst);
           app.n =1;
           if length(app.textLines{app.n}) >= 50
               currentTrial = InsertNewLine(app.textLines{app.n},50);
               currentTrial = char(currentTrial);
               app.UserCaseLabel.Text = char(currentTrial);
           else
               app.UserCaseLabel.Text = app.textLines{app.n};
           end
        end

        % Value changed function: 
        % EnterSessionID_UseCaseID_MaleIDFemaleIDEditField
        function EnterSessionID_UseCaseID_MaleIDFemaleIDEditFieldValueChanged(app, event)
            value = app.EnterSessionID_UseCaseID_MaleIDFemaleIDEditField.Value;
            app.IDlength = strlength(value);
            app.dashCounter = 0;
            app.dashArray = zeros(2,1);
            if (value(1) ~= "S")
                app.MustbeinthisformatS0001_U10_M100Label.FontColor=[1 0 0];
                app.sessionID_correct = 0;
                app.StartRecordingButton.Enable = 'off';
            else
                
                app.sessionID_correct = 1;
                app.StartRecordingButton.Enable = 'on';
            end
            if (value(1) == "S")
                app.MustbeinthisformatS0001_U10_M100Label.FontColor=[0.96 0.96 0.96];
            else
                app.sessionID_correct = 1;
                app.StartRecordingButton.Enable = 'on';
            end

                for n = 1:app.IDlength
                    if (value(n) == "_")
                        app.dashCounter = app.dashCounter + 1;
                        app.dashArray(app.dashCounter) = n;
                    end
                end
            app.firstDash = app.dashArray(1);
            app.secondDash = app.dashArray(2);
            app.sessionID = extractBefore(value, app.firstDash);
            
            app.sessionID = eraseBetween(app.sessionID,1,1);
            %disp(app.sessionID);
            app.sessionID = str2double(app.sessionID);
            if app.sessionID < 0 || app.sessionID > 9999
                app.MustbeinthisformatS0001_U10_M100Label.FontColor=[1 0 0];
                app.sessionID_correct = 0;
                app.StartRecordingButton.Enable = 'off';
            else
                app.sessionID_correct = 1;
                app.StartRecordingButton.Enable = 'on';
            end
            app.userCaseID = extractBetween(value, app.firstDash+1, app.secondDash-1);
            app.userLetter = eraseBetween(app.userCaseID,2,3);
            app.userLetter = string(app.userLetter);
            
    
            if (app.userLetter ~= "U")
                app.MustbeinthisformatS0001_U10_M100Label.FontColor=[1 0 0];
                app.sessionID_correct = 0;
                app.StartRecordingButton.Enable = 'off';
            end
            %S0001_U10_M100
            app.Male_Female_ID = extractAfter(value, app.secondDash);
            app.Male_Female_ID_Length = length(app.Male_Female_ID);
            app.M_F_letter = eraseBetween(app.Male_Female_ID, 2, app.Male_Female_ID_Length);
            app.M_F_letter = string(app.M_F_letter);
            app.M_F_num_ID = eraseBetween(app.Male_Female_ID,1,1);
            if app.M_F_letter ~= "M" && app.M_F_letter ~= "F"
                app.MustbeinthisformatS0001_U10_M100Label.FontColor=[1 0 0];
                app.sessionID_correct = 0;
                app.StartRecordingButton.Enable = 'off';
            else
                app.sessionID_correct = 1;
                app.StartRecordingButton.Enable = 'on';
            end
            %disp(app.sessionID_correct);
            
            
        end

        % Button pushed function: ListenToRecordingButton
        function ListenToRecordingButtonPushed(app, event)
            app.ListenToRecordingButton.BackgroundColor = [0.96 0.96 0.5];
            app.audio = getaudiodata(app.audioRecorder);
            
            sound(app.audio, app.fs);
            pause(length(app.audio));
            app.ListenToRecordingButton.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.n < app.totalTrials
                app.n = app.n + 1;
                app.UserCaseLabel.Text = app.textLines{app.n};
            else
                app.UserCaseLabel.Text = 'There are no more use cases, Thank you!';
            end
            if app.n > app.maxRecordedPrompt
                app.NextButton.Enable = 'off';
                app.hasGoneBack = false;
            end
            if app.n > 1
                app.PreviousButton_2.Enable = 'on';
            else
                app.PreviousButton_2.Enable = 'off';
            end

        end

        % Button pushed function: PreviousButton_2
        function PreviousButton_2Pushed(app, event)
            if app.n > 1
                app.n = app.n - 1;
                if length(app.textLines{app.n}) >= 50
                    currentTrial = InsertNewLine(app.textLines{app.n},50);
                    currentTrial = char(currentTrial);
                    app.UserCaseLabel.Text = currentTrial;
                else
                    app.UserCaseLabel.Text = app.textLines{app.n};
                end
            else
                disp('The prompt on screen is the first user case.');
            end
            if app.hasGoneBack
                app.NextButton.Enable = 'on';
            end
            app.hasGoneBack = true;
            if app.n > 1
                app.PreviousButton_2.Enable = 'on';
            else
                app.PreviousButton_2.Enable = 'off';
            end
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.StartRecordingButton.BackgroundColor = [1 0 0];
            app.audioRecorder = audiorecorder(app.fs, app.nBits, app.nChannels);
            record(app.audioRecorder);
            app.StartRecordingButton.Enable = "off";
        end

        % Button pushed function: StopRecordingButton
        function StopRecordingButtonPushed(app, event)
            stop(app.audioRecorder);
            app.StopRecordingButton.BackgroundColor = [1 0 0];
            app.StartRecordingButton.BackgroundColor = [0.96 0.96 0.96];
            pause(0.2);
            app.StopRecordingButton.BackgroundColor = [0.96 0.96 0.96];
            app.ListenToRecordingButton.BackgroundColor = [0.96 0.96 0.5];
            app.audio = getaudiodata(app.audioRecorder);
            sound(app.audio, app.fs);
            pause(length(app.audio) / app.fs);
            app.ListenToRecordingButton.BackgroundColor = [0.96 0.96 0.96];
            if app.n > app.maxRecordedPrompt
                app.maxRecordedPrompt = app.n;
            end
            app.StartRecordingButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelectNoiseLevelDropDownLabel = uilabel(app.UIFigure);
            app.SelectNoiseLevelDropDownLabel.Text = 'Select Noise Level';
            app.SelectNoiseLevelDropDown = uidropdown(app.UIFigure);
            app.SelectNoiseLevelDropDown.Items = {'0', '2'};
            app.SelectNoiseLevelDropDown.Value = '2';
            app.EnterSessionID_UseCaseID_MaleIDFemaleIDEditFieldLabel = uilabel(app.UIFigure);
            app.EnterSessionID_UseCaseID_MaleIDFemaleIDEditFieldLabel.Text = 'Enter: SessionID_UseCaseID_MaleID/FemaleID:';
            app.EnterSessionID_UseCaseID_MaleIDFemaleIDEditField = uieditfield(app.UIFigure, 'text');
            app.EnterSessionID_UseCaseID_MaleIDFemaleIDEditField.ValueChangedFcn = createCallbackFcn(app, @EnterSessionID_UseCaseID_MaleIDFemaleIDEditFieldValueChanged, true);
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.StopRecordingButton = uibutton(app.UIFigure, 'push');
            app.StopRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StopRecordingButtonPushed, true);
            app.StopRecordingButton.Text = 'Stop Recording';
            app.AcceptRecordingButton = uibutton(app.UIFigure, 'push');
            app.AcceptRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptRecordingButtonPushed, true);
            app.AcceptRecordingButton.Text = 'Accept Recording';
            app.PreviousButton_2 = uibutton(app.UIFigure, 'push');
            app.PreviousButton_2.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_2Pushed, true);
            app.PreviousButton_2.Text = 'Previous';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ListenToRecordingButton = uibutton(app.UIFigure, 'push');
            app.ListenToRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @ListenToRecordingButtonPushed, true);
            app.ListenToRecordingButton.Text = 'Listen To Recording';
            app.MustbeinthisformatS0001_U10_M100Label = uilabel(app.UIFigure);
            app.MustbeinthisformatS0001_U10_M100Label.Text = 'Must be in this format: S0001_U10_M100';
            app.BrowseUseCasetxtfileButton = uibutton(app.UIFigure, 'push');
            app.BrowseUseCasetxtfileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseUseCasetxtfileButtonPushed, true);
            app.BrowseUseCasetxtfileButton.Text = 'Browse Use Case .txt file';
            app.UserCaseLabel = uilabel(app.UIFigure);
            app.UserCaseLabel.Text = '';
            app.BrowseAudioSavePathButton = uibutton(app.UIFigure, 'push');
            app.BrowseAudioSavePathButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseAudioSavePathButtonPushed, true);
            app.BrowseAudioSavePathButton.Text = 'Browse Audio Save Path';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SRecord_V3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end