classdef STI_ALcons < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Switch                          matlab.ui.control.RockerSwitch
        CalculateButton                 matlab.ui.control.Button
        EXCELLENTLabel                  matlab.ui.control.Label
        GOODLabel                       matlab.ui.control.Label
        FAIRLabel                       matlab.ui.control.Label
        POORLabel                       matlab.ui.control.Label
        BADLabel                        matlab.ui.control.Label
        SpeechTransmissionIndexGauge    matlab.ui.control.LinearGauge
        SpeechTransmissionIndexGaugeLabel  matlab.ui.control.Label
        ALconsLabel                     matlab.ui.control.Label
        STIValueLabel                   matlab.ui.control.Label
        RT60at500HzLabel                matlab.ui.control.Label
        TotalAbsorbtionLabel            matlab.ui.control.Label
        ListenerFactorEditField         matlab.ui.control.NumericEditField
        ListenerFactorEditFieldLabel    matlab.ui.control.Label
        ModifierEditField               matlab.ui.control.NumericEditField
        ModifierEditFieldLabel          matlab.ui.control.Label
        DirectivityEditField            matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        OfEqualSourcesEditField         matlab.ui.control.NumericEditField
        OfEqualSourcesEditFieldLabel    matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightLabel                     matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthLabel                      matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthLabel                     matlab.ui.control.Label
        GenerateTableButton             matlab.ui.control.Button
        NumberofSurfacesEditField       matlab.ui.control.NumericEditField
        NumberofSurfacesEditFieldLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateTableButton
        function GenerateTableButtonPushed(app, event)
            num_surfaces = app.NumberofSurfacesEditField.Value;
           
            app.UITable.ColumnName = {'Material Name', 'Surface Area', 'Absorption Coefficient at 500 Hz'};
            app.UITable.Data = cell(num_surfaces, 3);
            app.UITable.ColumnEditable = true; 
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
    data = app.UITable.Data;
    num_surfaces = app.NumberofSurfacesEditField.Value;

    surface_areas = zeros(1, num_surfaces);
    absorption_coefficients = zeros(1, num_surfaces);
    for i = 1:num_surfaces
        surface_areas(i) = str2double(data{i, 2});
        absorption_coefficients(i) = str2double(data{i, 3});
    end

    % Calculate total absorption
    total_absorption_calculator = Total_Absorption_500Hz(num_surfaces, surface_areas, absorption_coefficients);
    total_absorption = total_absorption_calculator.Calculate_Total_Absorption_500Hz();

    % Get room dimensions
    length = app.LengthEditField.Value;
    width = app.WidthEditField.Value;
    height = app.HeightEditField.Value;

    % Volume
    volume = length * width * height;

    % num_equal_sources, volume, Q, M, K
    radius = app.RadiusEditField.Value;
    num_equal_sources = app.OfEqualSourcesEditField.Value;
    Q = app.DirectivityEditField.Value;
    M = app.ModifierEditField.Value;
    K = app.ListenerFactorEditField.Value;
    

    % Final Calculations
    rt60_calculator = RT60_Calculator(volume, total_absorption);
    rt60 = rt60_calculator.calculate_RT60();

    sti_calculator = STI_Calculator(rt60);
    sti = sti_calculator.Calculate_STI();

    ALconsCalculatorInstance = ALconsCalculator(radius, rt60, num_equal_sources, volume, Q, M, K);
    ALconsPercent = ALconsCalculatorInstance.CalculateALconsPercentage();

    % Update the labels with results, formatted to 2 decimal places
    app.TotalAbsorbtionLabel.Text = ['Total Absorption: ', sprintf('%.2f', total_absorption)];
    app.RT60at500HzLabel.Text = ['RT60: ', sprintf('%.2f', rt60)];
    app.STIValueLabel.Text = ['STI: ', sprintf('%.2f', sti)];
    app.ALconsLabel.Text = ['ALcons%: ', sprintf('%.2f', ALconsPercent)];

    % Update the gauge value
    app.SpeechTransmissionIndexGauge.Value = sti;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Metric"
                app.LengthEditField.Value = app.LengthEditField.Value * 0.3048;
                app.WidthEditField.Value = app.WidthEditField.Value * 0.3048;
                app.HeightEditField.Value = app.HeightEditField.Value * 0.3048;
            else
                app.LengthEditField.Value = app.LengthEditField.Value;
                app.WidthEditField.Value = app.WidthEditField.Value;
                app.HeightEditField.Value = app.HeightEditField.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.NumberofSurfacesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofSurfacesEditFieldLabel.Text = 'Number of Surfaces';
            app.NumberofSurfacesEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenerateTableButton = uibutton(app.UIFigure, 'push');
            app.GenerateTableButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTableButtonPushed, true);
            app.GenerateTableButton.Text = 'Generate Table';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length *';
            app.LengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthLabel = uilabel(app.UIFigure);
            app.WidthLabel.Text = 'Width *';
            app.WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightLabel = uilabel(app.UIFigure);
            app.HeightLabel.Text = 'Height *';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusEditFieldLabel = uilabel(app.UIFigure);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.OfEqualSourcesEditFieldLabel = uilabel(app.UIFigure);
            app.OfEqualSourcesEditFieldLabel.Text = '# Of Equal Sources';
            app.OfEqualSourcesEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Directivity';
            app.DirectivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModifierEditFieldLabel = uilabel(app.UIFigure);
            app.ModifierEditFieldLabel.Text = 'Modifier';
            app.ModifierEditField = uieditfield(app.UIFigure, 'numeric');
            app.ListenerFactorEditFieldLabel = uilabel(app.UIFigure);
            app.ListenerFactorEditFieldLabel.Text = 'Listener Factor';
            app.ListenerFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalAbsorbtionLabel = uilabel(app.UIFigure);
            app.TotalAbsorbtionLabel.Text = 'Total Absorbtion: ';
            app.RT60at500HzLabel = uilabel(app.UIFigure);
            app.RT60at500HzLabel.Text = 'RT60 at 500 Hz:';
            app.STIValueLabel = uilabel(app.UIFigure);
            app.STIValueLabel.Text = 'STI Value: ';
            app.ALconsLabel = uilabel(app.UIFigure);
            app.ALconsLabel.Text = 'ALcons%: ';
            app.SpeechTransmissionIndexGaugeLabel = uilabel(app.UIFigure);
            app.SpeechTransmissionIndexGaugeLabel.Text = 'Speech Transmission Index';
            app.SpeechTransmissionIndexGauge = uigauge(app.UIFigure, 'linear');
            app.SpeechTransmissionIndexGauge.Limits = [0 1];
            app.SpeechTransmissionIndexGauge.MajorTicks = [0 0.3 0.45 0.6 0.75 1];
            app.SpeechTransmissionIndexGauge.MinorTicks = [];
            app.BADLabel = uilabel(app.UIFigure);
            app.BADLabel.Text = 'BAD';
            app.POORLabel = uilabel(app.UIFigure);
            app.POORLabel.Text = 'POOR';
            app.FAIRLabel = uilabel(app.UIFigure);
            app.FAIRLabel.Text = 'FAIR';
            app.GOODLabel = uilabel(app.UIFigure);
            app.GOODLabel.Text = 'GOOD';
            app.EXCELLENTLabel = uilabel(app.UIFigure);
            app.EXCELLENTLabel.Text = 'EXCELLENT';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Switch = uiswitch(app.UIFigure, 'rocker');
            app.Switch.Items = {'Metric (m)', 'Imperial (ft)'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Imperial (ft)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STI_ALcons

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end